classdef Camera_Control_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ExposuretimesEditFieldLabel  matlab.ui.control.Label
        ExposuretimesEditField       matlab.ui.control.NumericEditField
        GaindBEditFieldLabel         matlab.ui.control.Label
        GaindBEditField              matlab.ui.control.NumericEditField
        XPixelEditFieldLabel         matlab.ui.control.Label
        XPixelEditField              matlab.ui.control.NumericEditField
        YPixelEditFieldLabel         matlab.ui.control.Label
        YPixelEditField              matlab.ui.control.NumericEditField
        RegionStartLabel             matlab.ui.control.Label
        XSizeMax2464EditFieldLabel   matlab.ui.control.Label
        XSizeMax2464EditField        matlab.ui.control.NumericEditField
        RegionSizeLabel              matlab.ui.control.Label
        YSizeMax2056EditFieldLabel   matlab.ui.control.Label
        YSizeMax2056EditField        matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        CameraIOC;
        ExposureTime;
        Gain;
        RegionStart;
        RegionSize;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, CameraIOC, Cameraname)
            % sets GUI name
            app.UIFigure.Name = [Cameraname ' Camera Controls'];
            
            % gets camera IOC name
            app.CameraIOC = CameraIOC;
            
            % gets initial readbacks and puts in text fields
            app.ExposuretimesEditField.Value = lcaGet([app.CameraIOC ':cam1:AcquireTime']);
            app.ExposureTime = app.ExposuretimesEditField.Value;
            app.GaindBEditField.Value = lcaGet([app.CameraIOC ':cam1:Gain']);
            app.Gain = app.GaindBEditField.Value;
            app.RegionStart = [lcaGet([app.CameraIOC ':cam1:MinX']) lcaGet([app.CameraIOC ':cam1:MinY'])];
            app.XPixelEditField.Value = app.RegionStart(1);
            app.YPixelEditField.Value = app.RegionStart(2);
            app.RegionSize = [lcaGet([app.CameraIOC ':cam1:SizeX']) lcaGet([app.CameraIOC ':cam1:SizeY'])];
            app.XSizeMax2464EditField.Value = app.RegionSize(1);
            app.YSizeMax2056EditField.Value = app.RegionSize(2);
        end

        % Value changed function: ExposuretimesEditField
        function ExposuretimesEditFieldValueChanged(app, event)
            % sets new exposure time value
            lcaPutNoWait([app.CameraIOC ':cam1:AcquireTime'],app.ExposuretimesEditField.Value)
            app.ExposureTime = app.ExposuretimesEditField.Value;
        end

        % Value changed function: GaindBEditField
        function GaindBEditFieldValueChanged(app, event)
            % sets new gain value
            lcaPutNoWait([app.CameraIOC ':cam1:Gain'],app.GaindBEditField.Value)
            app.Gain = app.GaindBEditField.Value;
        end

        % Value changed function: XPixelEditField
        function XPixelEditFieldValueChanged(app, event)
            % sets new x-pixel start value
            lcaPutNoWait([app.CameraIOC ':cam1:MinX'],app.XPixelEditField.Value)
            app.RegionStart(1) = app.XPixelEditField.Value;
        end

        % Value changed function: YPixelEditField
        function YPixelEditFieldValueChanged(app, event)
            % sets new y-pixel start value
            lcaPutNoWait([app.CameraIOC ':cam1:MinY'],app.YPixelEditField.Value)
            app.RegionStart(2) = app.YPixelEditField.Value;
        end

        % Value changed function: XSizeMax2464EditField
        function XSizeMax2464EditFieldValueChanged(app, event)
            % sets new x-size value
            lcaPutNoWait([app.CameraIOC ':cam1:SizeX'],app.XSizeMax2464EditField.Value)
            app.RegionSize(1) = app.XSizeMax2464EditField.Value;
        end

        % Value changed function: YSizeMax2056EditField
        function YSizeMax2056EditFieldValueChanged(app, event)
            % sets new y-size value
            lcaPutNoWait([app.CameraIOC ':cam1:SizeY'],app.YSizeMax2056EditField.Value)
            app.RegionSize(2) = app.YSizeMax2056EditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ExposuretimesEditFieldLabel = uilabel(app.UIFigure);
            app.ExposuretimesEditFieldLabel.Text = 'Exposure time (s)';
            app.ExposuretimesEditField = uieditfield(app.UIFigure, 'numeric');
            app.ExposuretimesEditField.ValueChangedFcn = createCallbackFcn(app, @ExposuretimesEditFieldValueChanged, true);
            app.GaindBEditFieldLabel = uilabel(app.UIFigure);
            app.GaindBEditFieldLabel.Text = 'Gain (dB)';
            app.GaindBEditField = uieditfield(app.UIFigure, 'numeric');
            app.GaindBEditField.ValueChangedFcn = createCallbackFcn(app, @GaindBEditFieldValueChanged, true);
            app.XPixelEditFieldLabel = uilabel(app.UIFigure);
            app.XPixelEditFieldLabel.Text = 'X Pixel';
            app.XPixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.XPixelEditField.ValueChangedFcn = createCallbackFcn(app, @XPixelEditFieldValueChanged, true);
            app.YPixelEditFieldLabel = uilabel(app.UIFigure);
            app.YPixelEditFieldLabel.Text = 'Y Pixel';
            app.YPixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.YPixelEditField.ValueChangedFcn = createCallbackFcn(app, @YPixelEditFieldValueChanged, true);
            app.RegionStartLabel = uilabel(app.UIFigure);
            app.RegionStartLabel.Text = 'Region Start';
            app.XSizeMax2464EditFieldLabel = uilabel(app.UIFigure);
            app.XSizeMax2464EditFieldLabel.Text = {'X Size'; 'Max: 2464'; ''};
            app.XSizeMax2464EditField = uieditfield(app.UIFigure, 'numeric');
            app.XSizeMax2464EditField.ValueChangedFcn = createCallbackFcn(app, @XSizeMax2464EditFieldValueChanged, true);
            app.RegionSizeLabel = uilabel(app.UIFigure);
            app.RegionSizeLabel.Text = 'Region Size';
            app.YSizeMax2056EditFieldLabel = uilabel(app.UIFigure);
            app.YSizeMax2056EditFieldLabel.Text = {'Y Size'; 'Max: 2056'; ''};
            app.YSizeMax2056EditField = uieditfield(app.UIFigure, 'numeric');
            app.YSizeMax2056EditField.ValueChangedFcn = createCallbackFcn(app, @YSizeMax2056EditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Camera_Control_GUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end