classdef Camera_Panel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        camera_embed                matlab.ui.Figure
        Panel                       matlab.ui.container.Panel
        Lamp                        matlab.ui.control.Lamp
        LoadImageButton             matlab.ui.control.StateButton
        CAMSettingsButton           matlab.ui.control.Button
        ISSIControlsButton          matlab.ui.control.Button
        LEDControlsButton           matlab.ui.control.Button
        Label                       matlab.ui.control.Label
        CAMImageButton              matlab.ui.control.StateButton
        AcquireCheckBox             matlab.ui.control.CheckBox
        ImageratefpsEditFieldLabel  matlab.ui.control.Label
        ImageratefpsEditField       matlab.ui.control.NumericEditField
        GoButton                    matlab.ui.control.Button
    end

    
    properties (Access = public)
        ISSI_IOC
        Camera_IOC
        ImageTimer
        MC_IOC
        Axis_name
        Cameraname;
        Controlgui;
        ISSIgui;
        LEDgui;
        Timer_name;
        Timer_name_Image;
        PasswordCorrect;
        CameraTimer_Name
        Free_Div_YAG_cam
        Free_Div_YAG_cam_Image
        E_beam_Exit_YAG_cam
        E_beam_Exit_YAG_cam_Image
        E_beam_Ent_YAG_cam
        E_beam_Ent_YAG_cam_Image
        E_beam_View_cam
        E_beam_View_cam_Image
        Laser_View_cam
        Laser_View_cam_Image
        Montel_cam
        Montel_cam_Image
    end
    
    methods (Access = private)
        
        function RefreshCamera(app)
            data = profmon_grab(app.Camera_IOC);
            profmon_imgPlot(data,'axes',app.Axis_name);
        end
        
        function RefreshCameraImage(app)
            data = profmon_grab(app.Camera_IOC);
            profmon_imgPlot(data);
        end
    end
    
    methods (Access = public)
        % properly kills the timer for the camera and stops the camera
        % acquiring
        function app = killCameraTimer(app,CameraIOC,Name)
              clear app.(Name)
              stop(app.(Name))
              delete(app.(Name))
              lcaPutNoWait([CameraIOC ':cam1:Acquire'],0)
        end
        
        % specifically used for the IP and Laser system GUIs because they
        % stream the same cameras so this ensures that if both are open,
        % the cameras do not stop acquiring when the GUI is closed
        function app = killCameraTimerDual(app,guipresent,cameraIOC,Name)
              clear app.(Name)
              stop(app.(Name))
              delete(app.(Name))
              if isempty(guipresent)
                  lcaPutNoWait([cameraIOC ':cam1:Acquire'],0)
              end
        end
        
        % function that asks to enter password and checks if it is
        % correct 
        function PasswordCheck(app)
                app.PasswordCorrect = 0;
                X = inputdlg('Enter Password');
                password = 'labuser'; % do we want a more secure password
                if strcmp(X, password)
                    app.PasswordCorrect = 1;
                else
                    app.PasswordCorrect = 0;
                end
            end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, visible, CameraIOC, MCIOC, ISSI_IOC, panelname, axisname, cameratimername)
            % sets if the elements of panel are visible
            if visible == 1
                app.camera_embed.Visible = 'on';
            else
                app.camera_embed.Visible = 'off';
            end
            
            % gets IOC names from inputs
            app.ISSI_IOC = ISSI_IOC;
            app.Camera_IOC = CameraIOC;
            app.MC_IOC = MCIOC;
            
            % sets panel name
            app.Cameraname = panelname;
            app.Label.Text = [panelname ' Camera'];
            
            % gets handle of axes that plot the image within the GUI
            app.Axis_name = axisname;
            
            % gets name of timer
            app.Timer_name = cameratimername;
            
            % gets status of if the camera is acquiring and sets the light
            % and checbox accordingly
            acquirestatus = lcaGet([app.Camera_IOC ':cam1:Acquire']);
            if strcmp(acquirestatus,'Acquire')
                app.Lamp.Color = 'g';
                app.AcquireCheckBox.Value = 1;
            elseif strcmp(acquirestatus,'Done')
                app.Lamp.Color = 'r';
                app.AcquireCheckBox.Value = 0;
            end
            
            % disables ISSI button for cameras that don't have them
            if strcmp(app.ISSI_IOC,'NA')
                app.ISSIControlsButton.Enable = 'off' ;
            end
            
            % sets timer and its properties
            app.(convertStringsToChars(app.Timer_name)) = timer('Name', convertStringsToChars(app.Timer_name), ...
                'Period', 0.25, ...   % FRAME RATE!!!!
                'StartDelay', 0.001, ...
                'TasksToExecute', inf, ...
                'ExecutionMode','FixedRate', ...
                'TimerFcn', @(varargin)RefreshCamera(app));
            
            % creates timer for the secondary image display and defines
            % camera properties
            app.Timer_name_Image = append(app.Timer_name, "_Image");
            app.(convertStringsToChars(app.Timer_name_Image)) = timer('Name', convertStringsToChars(app.Timer_name_Image), ...
                'Period', 0.25, ...   % FRAME RATE!!!!
                'StartDelay', 0.001, ...
                'TasksToExecute', inf, ...
                'ExecutionMode','FixedRate', ...
                'TimerFcn', @(varargin)RefreshCameraImage(app));
            
            % for update rate readback, puts in text field
            app.ImageratefpsEditField.Value = 1/app.(app.Timer_name).Period;
            
            t_Timer = app.(convertStringsToChars(app.Timer_name)) ;
            t_ImageTimer = app.(convertStringsToChars(app.Timer_name_Image));
            
        end

        % Value changed function: LoadImageButton
        function LoadImageButtonValueChanged(app, event)
            % on press to 1 starts timer for loading the image to the GUI
            % but if pressed to 0, stops timer and clears axes
            colorbar(app.Axis_name);
            if app.LoadImageButton.Value
                stop(app.(convertStringsToChars(app.Timer_name_Image)))
                start(app.(convertStringsToChars(app.Timer_name)))
            else
                stop(app.(convertStringsToChars(app.Timer_name)))
                cla(app.Axis_name,'reset');
            end
        end

        % Button pushed function: CAMSettingsButton
        function CAMSettingsButtonPushed(app, event)
            % opens GUI that allows changing camera settings and ROI
            cameragui = findall(0,'Name',[app.Cameraname ' Camera Controls']);
            if ~isempty(cameragui) == 0
                app.Controlgui =  Camera_Control_GUI(app.Camera_IOC,app.Cameraname);
            else    
            end
        end

        % Value changed function: CAMImageButton
        function CAMImageButtonValueChanged(app, event)
            % on press to 1 starts timer for loading the image to a
            % separate window, stops this timer, also has conditions based
            % on load image button
            if app.CAMImageButton.Value
                stop(app.(convertStringsToChars(app.Timer_name)))
                start(app.(convertStringsToChars(app.Timer_name_Image)))
                cla(app.Axis_name,'reset');
            else
                stop(app.(convertStringsToChars(app.Timer_name_Image)))
                if app.LoadImageButton.Value
                    start(app.(convertStringsToChars(app.Timer_name)))
                end
            end
        end

        % Button pushed function: ISSIControlsButton
        function ISSIControlsButtonPushed(app, event)
            % opens GUI with ISSI controls
            issigui = findall(0,'Name',[app.Cameraname ' ISSI Controls']);
            if ~isempty(issigui) == 0
                app.ISSIgui = ISSI_GUI(app.ISSI_IOC,app.Cameraname);
            else
            end
        end

        % Button pushed function: LEDControlsButton
        function LEDControlsButtonPushed(app, event)
            % opens GUI with LED controls
            ledgui = findall(0,'Name',['LED ' app.Cameraname ' Controls']);
            if ~isempty(ledgui) == 0
                app.LEDgui = LED_GUI(app.MC_IOC,app.Camera_IOC,app.Cameraname);
            else
            end
        end

        % Close request function: camera_embed
        function camera_embedCloseRequest(app, event)
            delete(app)
            
        end

        % Value changed function: AcquireCheckBox
        function AcquireCheckBoxValueChanged(app, event)
            % if pressed to 1, starts acquiring and sets lamp to green, if
            % pressed to 0 then lamp to red and stops acquiring
            if app.AcquireCheckBox.Value
                app.Lamp.Color = 'g';
                lcaPutNoWait([app.Camera_IOC ':cam1:Acquire'],1)
            else
                app.Lamp.Color = 'r';
                lcaPutNoWait([app.Camera_IOC ':cam1:Acquire'],0)
            end
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            % allows for changing the image update rate (really the timer
            % period) and also stops the timer to do this change and then
            % restarts the timer based on the buttons' status
            PasswordCheck(app)
            if app.PasswordCorrect
                stop(app.(convertStringsToChars(app.Timer_name)))
                stop(app.(convertStringsToChars(app.Timer_name_Image)))
                app.(app.Timer_name).Period = 1/app.ImageratefpsEditField.Value;
                app.(app.Timer_name_Image).Period = 1/app.ImageratefpsEditField.Value;
                if app.LoadImageButton.Value && app.CAMImageButton.Value == 0
                    start(app.(convertStringsToChars(app.Timer_name)))
                elseif app.CAMImageButton.Value
                    start(app.(convertStringsToChars(app.Timer_name_Image)))
                end
            else
                errordlg('Incorrect Password')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.camera_embed = uifigure('Visible', 'off');
            app.camera_embed.Name = 'MATLAB App';
            app.camera_embed.CloseRequestFcn = createCallbackFcn(app, @camera_embedCloseRequest, true);
            app.Panel = uipanel(app.camera_embed);
            app.Lamp = uilamp(app.Panel);
            app.LoadImageButton = uibutton(app.Panel, 'state');
            app.LoadImageButton.ValueChangedFcn = createCallbackFcn(app, @LoadImageButtonValueChanged, true);
            app.LoadImageButton.Text = 'Load Image';
            app.CAMSettingsButton = uibutton(app.Panel, 'push');
            app.CAMSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @CAMSettingsButtonPushed, true);
            app.CAMSettingsButton.Text = 'CAM Settings';
            app.ISSIControlsButton = uibutton(app.Panel, 'push');
            app.ISSIControlsButton.ButtonPushedFcn = createCallbackFcn(app, @ISSIControlsButtonPushed, true);
            app.ISSIControlsButton.Text = 'ISSI Controls';
            app.LEDControlsButton = uibutton(app.Panel, 'push');
            app.LEDControlsButton.ButtonPushedFcn = createCallbackFcn(app, @LEDControlsButtonPushed, true);
            app.LEDControlsButton.Text = 'LED Controls';
            app.Label = uilabel(app.Panel);
            app.CAMImageButton = uibutton(app.Panel, 'state');
            app.CAMImageButton.ValueChangedFcn = createCallbackFcn(app, @CAMImageButtonValueChanged, true);
            app.CAMImageButton.Text = 'CAM Image';
            app.AcquireCheckBox = uicheckbox(app.Panel);
            app.AcquireCheckBox.ValueChangedFcn = createCallbackFcn(app, @AcquireCheckBoxValueChanged, true);
            app.AcquireCheckBox.Text = 'Acquire';
            app.ImageratefpsEditFieldLabel = uilabel(app.Panel);
            app.ImageratefpsEditFieldLabel.Text = 'Image rate (fps)';
            app.ImageratefpsEditField = uieditfield(app.Panel, 'numeric');
            app.GoButton = uibutton(app.Panel, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            % Show the figure after all components are created
            app.camera_embed.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Camera_Panel(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.camera_embed)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.camera_embed)
        end
    end
end