classdef Collimated_Xray_YAG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Image                matlab.ui.control.Image
        Panel_1              matlab.ui.container.Panel
        Panel_2              matlab.ui.container.Panel
        YAGPositioningLabel  matlab.ui.control.Label
        Panel_4              matlab.ui.container.Panel
        Panel_3              matlab.ui.container.Panel
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        % create object for each GUI that needs to be embedded
        % to add other panels, call property testobj#
        testobj1 = [];
        testobj2 = [];
        cameraobj = [];
        TimerNames;
        Camera_IOC;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % makes parent GUI invisible while child GUI is initialized
            app.UIFigure.Visible = 'off';
            app.UIFigure.Name = 'Collimated X-ray YAG';
            % names of IOCs and panels that will be passed to child GUIs
            % to finish GUI, fill in proper IOC, Camera IOC, and timer
            % names
            IOCnames = ["MOTR:ICS1:50:H" "MOTR:ICS1:50:V"];
            Names = ["X-AXIS (mm)" "Y-AXIS (mm)"];
            app.TimerNames = "Collimated_xray_YAG";
            ISSI_IOC = 'NA';
            app.Camera_IOC = 'CAM:ICS1:70';
            MCIOC = 'LED:ICS1:Bo4';
            units = ["mm" "mm"];

            % Preset names that will be passed to child GUIs
            % N.B. If you want to change the number of presets, every line
            % in the file must have the same number of presets to work
            % properly. If you want to maintain a record of the file with a
            % different number of presets, duplicate and save the file.
            XPresetNames = {'IN','CP1'};
            YPresetNames = {'P1 - 3','P2 - 2.5','P3 - 2','P4 - 1.5','P5 - 1','P6 - 0.5','YAG','OUT','CP1'};
            
            %combined into single cell that way we only need to index this
            %cell to use the set of preset names as an input argument
            Presets = {XPresetNames ; YPresetNames};
            
            % creates structure to store elements of child GUIs
            hobjects(1:length(IOCnames)) = struct();
            camobjects = struct();
            
            % Loop sets property for child GUI to the child GUI, fills the
            % structure with the child GUI elements and passes IOC name to
            % child GUI
            for ii = 1:length(IOCnames)
                app.(['testobj' num2str(ii)]) = embed_GUI(0,Names{ii},Presets{ii},convertStringsToChars(IOCnames(ii)),convertStringsToChars(app.TimerNames),app.UIFigure.Name,convertStringsToChars(units(ii))); % test_embed is linked gui
                hobjects(ii).MyNewField = app.(['testobj' num2str(ii)]).embeddedapp.Children;   
            end
            
            % loads property for camera GUI, fills the struct with the GUI
            % elements and passes needed input arguments
            app.cameraobj = Camera_Panel(0,app.Camera_IOC,MCIOC,ISSI_IOC,'Collimated X-ray YAG',app.UIAxes,"Collimated_YAG_cam");
            camobjects.MyNewField = app.cameraobj.camera_embed.Children;
            
            
           % now that child GUI is initialized, make parent GUI visible
           app.UIFigure.Visible = 'on';
           
           % puts camera GUI on parent GUI
           for idx = 1:length(camobjects)
                camobjects.MyNewField.Parent = app.Panel_4;
           end
            
           % makes camera GUI alone invisible 
            app.cameraobj.camera_embed.Visible = 'off';
            
           % loop puts child GUIs in panels on parent GUI and passes name
           % for the panel and IOC name to child GUI
           for iii = 1:length(IOCnames)
                for idx = 1: length((['hobjects' num2str(iii)]))
                    hobjects(iii).MyNewField.Parent = app.(['Panel_' num2str(iii)]);
                end
                
                % makes child GUIs as individual GUIs invisible
                app.(['testobj' num2str(iii)]).embeddedapp.Visible = 'off';
           end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % kills all timers when GUI closes
            app.testobj1.killTimer(convertStringsToChars(app.TimerNames));
            app.testobj2.killTimer(convertStringsToChars(app.TimerNames));
            app.cameraobj.killCameraTimer(app.Camera_IOC,convertStringsToChars("Collimated_YAG_cam"));
            app.cameraobj.killCameraTimer(app.Camera_IOC,convertStringsToChars("Collimated_YAG_cam_Image"));
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Collimated X-ray YAG.png';
            app.Panel_1 = uipanel(app.UIFigure);
            app.Panel_2 = uipanel(app.UIFigure);
            app.YAGPositioningLabel = uilabel(app.UIFigure);
            app.YAGPositioningLabel.Text = 'YAG Positioning';
            app.Panel_4 = uipanel(app.UIFigure);
            app.Panel_3 = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel_3);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Collimated_Xray_YAG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end