classdef E_beam_Entrance_YAG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Image                   matlab.ui.control.Image
        Panel_2                 matlab.ui.container.Panel
        MirrorPositioningLabel  matlab.ui.control.Label
        Panel_3                 matlab.ui.container.Panel
        Panel_1                 matlab.ui.container.Panel
        YAGPositioningLabel     matlab.ui.control.Label
        Panel_5                 matlab.ui.container.Panel
        Panel_4                 matlab.ui.container.Panel
        UIAxes                  matlab.ui.control.UIAxes
        HomeStackButton         matlab.ui.control.Button
        LogTextAreaLabel        matlab.ui.control.Label
        LogTextArea             matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        testobj1 = [];
        testobj2 = [];
        testobj3 = [];
        cameraobj = [];
        TimerNames;
        Camera_IOC;
        TerminalText;
        PasswordCorrect;
    end
    
    methods (Access = private)
        function AddToTerminal(app,AddOnText)
            app.TerminalText = [app.TerminalText newline AddOnText] ;
            app.LogTextArea.Value = app.TerminalText ;
            pause(0.01)
            scroll(app.LogTextArea, 'bottom')
        end
        
        function PasswordCheck(app)
            app.PasswordCorrect = 0;
            X = inputdlg('Enter Password');
            password = 'labuser'; % do we want a more secure password
            if strcmp(X, password)
                app.PasswordCorrect = 1;
            else
                app.PasswordCorrect = 0;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             % makes parent GUI invisible while child GUI is initialized
            app.UIFigure.Visible = 'off';
            app.UIFigure.Name = 'E-beam Entrance YAG';
            % names of IOCs and panels that will be passed to child GUIs
            % to finish GUI, fill in proper IOC, Camera IOC, and timer
            % names
            IOCnames = ["MOTR:ICS1:10:H" "MOTR:ICS1:10:V" "MOTR:ICS1:110:PHI"];
            Names = ["X-AXIS (mm)" "Y-AXIS (mm)" "Theta-AXIS (deg)"];
            app.TimerNames = "Ebeam_Ent_YAG";
            ISSI_IOC = 'NA'; % Camera 1 does not have ISSI this is just a placeholder
            app.Camera_IOC = 'CAM:ICS1:10';
            MCIOC = 'LED:ICS1:Bo2'; % actual will be Bo1
            units = ["mm" "mm" "deg"];

            % Preset names that will be passed to child GUIs
            % N.B. If you want to change the number of presets, every line
            % in the file must have the same number of presets to work
            % properly. If you want to maintain a record of the file with a
            % different number of presets, duplicate and save the file.
            XPresetNames = {'P1 - 0.3','P2 - 0.5','P3 - 0.7','P4 - 1','P5 - 1.5','P6 - 2.5','YAG','OUT','CP1'};
            YPresetNames = {'OUT','IN','CP1'};
            ThetaPresetNames = {'P1','P2','P3','CP1'};
            
            %combined into single cell that way we only need to index this
            %cell to use the set of preset names as an input argument
            Presets = {XPresetNames ; YPresetNames; ThetaPresetNames};
            
            % creates structure to store elements of child GUIs for axes
            % and camera
            hobjects(1:length(IOCnames)) = struct();
            camobjects = struct();
            
            % Loop sets property for child GUI to the child GUI, fills the
            % structure with the child GUI elements and passes IOC name to
            % child GUI
            for ii = 1:length(IOCnames)
                app.(['testobj' num2str(ii)]) = embed_GUI(0,Names{ii},Presets{ii},convertStringsToChars(IOCnames(ii)),convertStringsToChars(app.TimerNames),app.UIFigure.Name,convertStringsToChars(units(ii))); % test_embed is linked gui
                hobjects(ii).MyNewField = app.(['testobj' num2str(ii)]).embeddedapp.Children;   
            end
            
            % loads property for camera GUI, fills the struct with the GUI
            % elements and passes needed input arguments
            app.cameraobj = Camera_Panel(0,app.Camera_IOC,MCIOC,ISSI_IOC,'E-beam Entrance YAG',app.UIAxes,"E_beam_Ent_YAG_cam");
            camobjects.MyNewField = app.cameraobj.camera_embed.Children;
           % now that child GUI is initialized, make parent GUI visible
           app.UIFigure.Visible = 'on';
           
           % puts camera GUI on parent GUI
           for idx = 1:length(camobjects)
                camobjects.MyNewField.Parent = app.Panel_5;
           end
            
            % makes camera GUI alone invisible 
            app.cameraobj.camera_embed.Visible = 'off';
           
           % loop puts child GUIs in panels on parent GUI 
           for iii = 1:length(IOCnames)
                for idx = 1: length((['hobjects' num2str(iii)]))
                    hobjects(iii).MyNewField.Parent = app.(['Panel_' num2str(iii)]);
                end
                
                % makes child GUIs as individual GUIs invisible
                app.(['testobj' num2str(iii)]).embeddedapp.Visible = 'off';
           end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % kills all timers when GUI closes
            app.testobj1.killTimer(convertStringsToChars(app.TimerNames));
            app.testobj2.killTimer(convertStringsToChars(app.TimerNames));
            app.testobj3.killTimer(convertStringsToChars(app.TimerNames));
            app.cameraobj.killCameraTimer(app.Camera_IOC,convertStringsToChars("E_beam_Ent_YAG_cam"));
            app.cameraobj.killCameraTimer(app.Camera_IOC,convertStringsToChars("E_beam_Ent_YAG_cam_Image"));
            delete(app)
        end

        % Button pushed function: HomeStackButton
        function HomeStackButtonPushed(app, event)
            PasswordCheck(app)
            if app.PasswordCorrect    
                lcaPutNoWait('MOTR:ICS1:10:H-home',1)
                AddToTerminal(app,'Homing x-axis. This will take 30 seconds.')
                pause(30)
                AddToTerminal(app,'X-axis homed.')
                lcaPutNoWait('MOTR:ICS1:10:V-home',1)
                AddToTerminal(app,'Homing y-axis. This will take 30 seconds.')
                pause(35)
                AddToTerminal(app,'Y-axis homed.')
                AddToTerminal(app,'E-beam Entrance YAG stage stack homed.')
            else
                errordlg('Incorrect password')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'E-beam Entrance YAG.png';
            app.Panel_2 = uipanel(app.UIFigure);
            app.MirrorPositioningLabel = uilabel(app.UIFigure);
            app.MirrorPositioningLabel.Text = 'Mirror Positioning';
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_1 = uipanel(app.UIFigure);
            app.YAGPositioningLabel = uilabel(app.UIFigure);
            app.YAGPositioningLabel.Text = 'YAG Positioning';
            app.Panel_5 = uipanel(app.UIFigure);
            app.Panel_4 = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel_4);
            title(app.UIAxes, {''; ''})
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.HomeStackButton = uibutton(app.UIFigure, 'push');
            app.HomeStackButton.ButtonPushedFcn = createCallbackFcn(app, @HomeStackButtonPushed, true);
            app.HomeStackButton.Text = 'Home Stack';
            app.LogTextAreaLabel = uilabel(app.UIFigure);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = E_beam_Entrance_YAG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end