classdef ICSIP_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Image                       matlab.ui.control.Image
        CXLSICSIPChamberControlsDashboardLabel  matlab.ui.control.Label
        EBeamExitYAGButton          matlab.ui.control.Button
        MONTELButton                matlab.ui.control.Button
        IRENTMirrorButton           matlab.ui.control.Button
        IRENTLensButton             matlab.ui.control.Button
        LaserSystemButton           matlab.ui.control.Button
        IREXITLensButton            matlab.ui.control.Button
        IREXITMirrorButton          matlab.ui.control.Button
        ICSIPYAGButton              matlab.ui.control.Button
        EbeamEntYAGButton           matlab.ui.control.Button
        CollimatedXrayYAGButton     matlab.ui.control.Button
        FreeDivergentXrayYAGButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % loads needed file paths, for EPICS labCA and preset files
            app.UIFigure.Name = 'ASU CXLS ICS IP GUI';
            addpath('/usr/local/EPICS_NEW/labca_3_7_2/bin/linux-x86_64/labca/')
            addpath('~/Documents/MATLAB/ICS-IP_GUI_PRODUCTION_PRESETS/')
            % all buttons have if statements that prevent opening multiple
            % instances of each GUI
        end

        % Button pushed function: EbeamEntYAGButton
        function EbeamEntYAGButtonPushed(app, event)
            GUI = findall(0, 'Name', 'E-beam Entrance YAG');
            if ~isempty(GUI) == 0
                E_beam_Entrance_YAG
            else
            end
        end

        % Button pushed function: EBeamExitYAGButton
        function EBeamExitYAGButtonPushed(app, event)
            GUI = findall(0, 'Name', 'E-beam Exit YAG');
            if ~isempty(GUI) == 0
                E_beam_Exit_YAG
            else
            end
        end

        % Button pushed function: CollimatedXrayYAGButton
        function CollimatedXrayYAGButtonPushed(app, event)
            GUI = findall(0, 'Name', 'Collimated X-ray YAG');
            if ~isempty(GUI) == 0
                Collimated_Xray_YAG
            else
            end
        end

        % Button pushed function: FreeDivergentXrayYAGButton
        function FreeDivergentXrayYAGButtonPushed(app, event)
            GUI = findall(0, 'Name', 'Free Divergent YAG');
            if ~isempty(GUI) == 0
                Free_Divergent_YAG
            else
            end
        end

        % Button pushed function: ICSIPYAGButton
        function ICSIPYAGButtonPushed(app, event)
            GUI = findall(0, 'Name', 'Interaction Point YAG');
            if ~isempty(GUI) == 0
                Interaction_point_YAG
            else
            end
        end

        % Button pushed function: LaserSystemButton
        function LaserSystemButtonPushed(app, event)
            GUI = findall(0, 'Name', 'Laser Dashboard');
            if ~isempty(GUI) == 0
                Laser_dashboard
            else
            end
        end

        % Button pushed function: IRENTLensButton
        function IRENTLensButtonPushed(app, event)
            GUI = findall(0, 'Name', 'IR Entrance Lens');
            if ~isempty(GUI) == 0
                IR_entrance_lens
            else
            end
        end

        % Button pushed function: IREXITLensButton
        function IREXITLensButtonPushed(app, event)
            GUI = findall(0, 'Name', 'IR Exit Lens');
            if ~isempty(GUI) == 0
                IR_exit_lens
            else
            end
        end

        % Button pushed function: IRENTMirrorButton
        function IRENTMirrorButtonPushed(app, event)
            GUI = findall(0, 'Name', 'IR Entrance Mirror');
            if ~isempty(GUI) == 0
                IR_entrance_mirror
            else
            end
        end

        % Button pushed function: IREXITMirrorButton
        function IREXITMirrorButtonPushed(app, event)
            GUI = findall(0, 'Name', 'IR Exit Mirror');
            if ~isempty(GUI) == 0
                IR_exit_mirror
            else
            end
        end

        % Button pushed function: MONTELButton
        function MONTELButtonPushed(app, event)
            GUI = findall(0, 'Name', 'Montel Optic');
            if ~isempty(GUI) == 0
                Montel_optic
            else
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Main_dashboard.png';
            app.CXLSICSIPChamberControlsDashboardLabel = uilabel(app.UIFigure);
            app.CXLSICSIPChamberControlsDashboardLabel.Text = 'CXLS ICS-IP Chamber Controls Dashboard';
            app.EBeamExitYAGButton = uibutton(app.UIFigure, 'push');
            app.EBeamExitYAGButton.ButtonPushedFcn = createCallbackFcn(app, @EBeamExitYAGButtonPushed, true);
            app.EBeamExitYAGButton.Text = 'E-Beam Exit YAG';
            app.MONTELButton = uibutton(app.UIFigure, 'push');
            app.MONTELButton.ButtonPushedFcn = createCallbackFcn(app, @MONTELButtonPushed, true);
            app.MONTELButton.Text = 'MONTEL';
            app.IRENTMirrorButton = uibutton(app.UIFigure, 'push');
            app.IRENTMirrorButton.ButtonPushedFcn = createCallbackFcn(app, @IRENTMirrorButtonPushed, true);
            app.IRENTMirrorButton.Text = 'IR ENT Mirror';
            app.IRENTLensButton = uibutton(app.UIFigure, 'push');
            app.IRENTLensButton.ButtonPushedFcn = createCallbackFcn(app, @IRENTLensButtonPushed, true);
            app.IRENTLensButton.Text = 'IR ENT Lens';
            app.LaserSystemButton = uibutton(app.UIFigure, 'push');
            app.LaserSystemButton.ButtonPushedFcn = createCallbackFcn(app, @LaserSystemButtonPushed, true);
            app.LaserSystemButton.Text = 'Laser System';
            app.IREXITLensButton = uibutton(app.UIFigure, 'push');
            app.IREXITLensButton.ButtonPushedFcn = createCallbackFcn(app, @IREXITLensButtonPushed, true);
            app.IREXITLensButton.Text = 'IR EXIT Lens';
            app.IREXITMirrorButton = uibutton(app.UIFigure, 'push');
            app.IREXITMirrorButton.ButtonPushedFcn = createCallbackFcn(app, @IREXITMirrorButtonPushed, true);
            app.IREXITMirrorButton.Text = 'IR EXIT Mirror';
            app.ICSIPYAGButton = uibutton(app.UIFigure, 'push');
            app.ICSIPYAGButton.ButtonPushedFcn = createCallbackFcn(app, @ICSIPYAGButtonPushed, true);
            app.ICSIPYAGButton.Text = 'ICS-IP YAG';
            app.EbeamEntYAGButton = uibutton(app.UIFigure, 'push');
            app.EbeamEntYAGButton.ButtonPushedFcn = createCallbackFcn(app, @EbeamEntYAGButtonPushed, true);
            app.EbeamEntYAGButton.Text = 'E-beam Ent YAG';
            app.CollimatedXrayYAGButton = uibutton(app.UIFigure, 'push');
            app.CollimatedXrayYAGButton.ButtonPushedFcn = createCallbackFcn(app, @CollimatedXrayYAGButtonPushed, true);
            app.CollimatedXrayYAGButton.Text = 'Collimated X-ray YAG';
            app.FreeDivergentXrayYAGButton = uibutton(app.UIFigure, 'push');
            app.FreeDivergentXrayYAGButton.ButtonPushedFcn = createCallbackFcn(app, @FreeDivergentXrayYAGButtonPushed, true);
            app.FreeDivergentXrayYAGButton.Text = 'Free Divergent X-ray YAG';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ICSIP_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end