classdef IR_exit_lens < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Image             matlab.ui.control.Image
        Panel_1           matlab.ui.container.Panel
        Panel_2           matlab.ui.container.Panel
        Panel_3           matlab.ui.container.Panel
        HomestackButton   matlab.ui.control.Button
        LogTextAreaLabel  matlab.ui.control.Label
        LogTextArea       matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        testobj1 = [];
        testobj2 = [];
        testobj3 = [];
        TimerNames;
        TerminalText;
        PasswordCorrect;
    end
    
    methods (Access = private)
        function AddToTerminal(app,AddOnText)
            app.TerminalText = [app.TerminalText newline AddOnText] ;
            app.LogTextArea.Value = app.TerminalText ;
            pause(0.01)
            scroll(app.LogTextArea, 'bottom')
        end
        
        function PasswordCheck(app)
            app.PasswordCorrect = 0;
            X = inputdlg('Enter Password');
            password = 'labuser'; % do we want a more secure password
            if strcmp(X, password)
                app.PasswordCorrect = 1;
            else
                app.PasswordCorrect = 0;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             % makes parent GUI invisible while child GUI is initialized
            app.UIFigure.Visible = 'off';
            app.UIFigure.Name = 'IR Exit Lens';
            % names of IOCs and panels that will be passed to child GUIs
            % to finish GUI, fill in proper IOC, Camera IOC, and timer
            % names
            IOCnames = ["MOTR:ICS1:100:H" "MOTR:ICS1:100:V" "MOTR:ICS1:100:Z"];
            Names = ["X-AXIS (mm)" "Y-AXIS (mm)" "Z-AXIS (mm)"];
            app.TimerNames = "IR_Exit_Lens";
            units = ["mm" "mm" "mm"];
            
            % Preset names that will be passed to child GUIs
            % N.B. If you want to change the number of presets, every line
            % in the file must have the same number of presets to work
            % properly. If you want to maintain a record of the file with a
            % different number of presets, duplicate and save the file.
            XPresetNames = {'OUT','IN' , 'CP1', 'CP2'};
            YPresetNames = {'OUT','IN' , 'CP1', 'CP2'};
            ZPresetNames = {'OUT','IN' , 'CP1', 'CP2'};
            
            %combined into single cell that way we only need to index this
            %cell to use the set of preset names as an input argument
            Presets = {XPresetNames ; YPresetNames; ZPresetNames};
            
            % creates structure to store elements of child GUIs
            hobjects(1:length(IOCnames)) = struct();
            
            % Loop sets property for child GUI to the child GUI, fills the
            % structure with the child GUI elements and passes IOC name to
            % child GUI
            for ii = 1:length(IOCnames)
                app.(['testobj' num2str(ii)]) = embed_GUI(0,Names{ii},Presets{ii},convertStringsToChars(IOCnames(ii)),convertStringsToChars(app.TimerNames),app.UIFigure.Name,convertStringsToChars(units(ii))); % test_embed is linked gui
                hobjects(ii).MyNewField = app.(['testobj' num2str(ii)]).embeddedapp.Children;   
            end
           % now that child GUI is initialized, make parent GUI visible
           app.UIFigure.Visible = 'on';
           
           % loop puts child GUIs in panels on parent GUI and passes name
           % for the panel and IOC name to child GUI
           for iii = 1:length(IOCnames)
                for idx = 1: length((['hobjects' num2str(iii)]))
                    hobjects(iii).MyNewField.Parent = app.(['Panel_' num2str(iii)]);
                end
                
                % makes child GUIs as individual GUIs invisible
                app.(['testobj' num2str(iii)]).embeddedapp.Visible = 'off';
           end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % kills all timers when GUI closes
            app.testobj1.killTimer(convertStringsToChars(app.TimerNames));
            app.testobj2.killTimer(convertStringsToChars(app.TimerNames));
            app.testobj3.killTimer(convertStringsToChars(app.TimerNames));
            delete(app)
        end

        % Button pushed function: HomestackButton
        function HomestackButtonPushed(app, event)
            PasswordCheck(app)
            if app.PasswordCorrect
                lcaPutNoWait('MOTR:ICS1:100:Z-home',1)
                AddToTerminal(app,'Homing z-axis. This will take 35 seconds.')
                pause(35)
                AddToTerminal(app,'Z-axis homed.')
                lcaPutNoWait('MOTR:ICS1:100:Z.VAL',4.5)
                AddToTerminal(app,'Moving z-axis to 4.5 mm. This will take 20 seconds.')
                pause(20)
                AddToTerminal(app,'Z-axis at 4.5 mm.')
                lcaPutNoWait('MOTR:ICS1:100:V-home',1)
                AddToTerminal(app,'Homing y-axis. This will take 35 seconds.')
                pause(35)
                AddToTerminal(app,'Y-axis homed.')
                lcaPutNoWait('MOTR:ICS1:100:H-home',1)
                AddToTerminal(app,'Homing x-axis. This will take 30 seconds.')
                pause(30)
                AddToTerminal(app,'X-axis homed.')
                lcaPutNoWait('MOTR:ICS1:100:Z.VAL',0)
                AddToTerminal(app,'Moving z-axis to 0 mm. This will take 20 seconds.')
                pause(20)
                AddToTerminal(app,'Z-axis at 0 mm.')
                AddToTerminal(app, 'IR Exit Lens stage stack homed.')
            else
                errordlg('Incorrect password')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'IR exit lens.png';
            app.Panel_1 = uipanel(app.UIFigure);
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_3 = uipanel(app.UIFigure);
            app.HomestackButton = uibutton(app.UIFigure, 'push');
            app.HomestackButton.ButtonPushedFcn = createCallbackFcn(app, @HomestackButtonPushed, true);
            app.HomestackButton.Text = 'Home stack';
            app.LogTextAreaLabel = uilabel(app.UIFigure);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IR_exit_lens

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end