classdef ISSI_edit_presets < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        CurrentFocusLabel     matlab.ui.control.Label
        NewFocusLabel         matlab.ui.control.Label
        CurrentFocus          matlab.ui.control.NumericEditField
        NewFocus              matlab.ui.control.NumericEditField
        SaveFocus             matlab.ui.control.Button
        PRESETSDropDownLabel  matlab.ui.control.Label
        PRESETSDropDown       matlab.ui.control.DropDown
        EditISSIPresetsLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        FocusPresets
        IOC_name
        Panel_name
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, IOCname, Panelname)
            % gets IOC name
            app.IOC_name = IOCname;
            
            % sets GUI name
            app.Panel_name = Panelname;
            app.UIFigure.Name = [app.Panel_name ' ISSI Edit Presets'];
            
            % reads presets file
            FocusPresetsTextFileData = readcell([app.IOC_name '_Focus_Presets.txt']);
            app.FocusPresets = [FocusPresetsTextFileData{end,1:(length(FocusPresetsTextFileData)-1)}] ;
            
            % makes readback edit field un-editable
            app.CurrentFocus.Editable = 'off';
            
            % loads first readback on startup to edit field
            app.CurrentFocus.Value = app.FocusPresets(1);
        end

        % Button pushed function: SaveFocus
        function SaveFocusButtonPushed(app, event)
            % saves new preset value to preset file
            app.CurrentFocus.Value = app.NewFocus.Value;
            for ii = 1:length(app.FocusPresets)
                 if strcmp(app.PRESETSDropDown.Value,app.PRESETSDropDown.Items(ii))
                     app.FocusPresets(ii) = app.NewFocus.Value;
                     writtentext = {app.FocusPresets,datestr(datetime('now'))};
                     writecell(writtentext,[app.IOC_name '_Focus_Presets.txt'],'WriteMode','append')
                 else
                 end
            end
        end

        % Value changed function: PRESETSDropDown
        function PRESETSDropDownValueChanged(app, event)
            % changes preset shown in readback
            for i = 1:length(app.FocusPresets)
                if strcmp(app.PRESETSDropDown.Value,app.PRESETSDropDown.Items(i))
                    app.CurrentFocus.Value = app.FocusPresets(i);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CurrentFocusLabel = uilabel(app.UIFigure);
            app.CurrentFocusLabel.Text = 'Current Focus';
            app.NewFocusLabel = uilabel(app.UIFigure);
            app.NewFocusLabel.Text = 'New Focus';
            app.CurrentFocus = uieditfield(app.UIFigure, 'numeric');
            app.CurrentFocus.ValueDisplayFormat = '%.0f';
            app.NewFocus = uieditfield(app.UIFigure, 'numeric');
            app.NewFocus.ValueDisplayFormat = '%.0f';
            app.SaveFocus = uibutton(app.UIFigure, 'push');
            app.SaveFocus.ButtonPushedFcn = createCallbackFcn(app, @SaveFocusButtonPushed, true);
            app.SaveFocus.Text = 'SAVE';
            app.PRESETSDropDownLabel = uilabel(app.UIFigure);
            app.PRESETSDropDownLabel.Text = 'PRESETS:';
            app.PRESETSDropDown = uidropdown(app.UIFigure);
            app.PRESETSDropDown.Items = {'P1', 'P2', 'P3', 'P4'};
            app.PRESETSDropDown.ValueChangedFcn = createCallbackFcn(app, @PRESETSDropDownValueChanged, true);
            app.PRESETSDropDown.Value = 'P1';
            app.EditISSIPresetsLabel = uilabel(app.UIFigure);
            app.EditISSIPresetsLabel.Text = 'Edit ISSI Presets';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ISSI_edit_presets(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end