classdef LED_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        LEDstatusSwitchLabel         matlab.ui.control.Label
        LEDstatusSwitch              matlab.ui.control.Switch
        ExposuretimesEditFieldLabel  matlab.ui.control.Label
        ExposuretimesEditField       matlab.ui.control.NumericEditField
        GaindBEditFieldLabel         matlab.ui.control.Label
        GaindBEditField              matlab.ui.control.NumericEditField
        SAVEButton                   matlab.ui.control.Button
    end

    
    properties (Access = public)
        MCIOC;
        CameraIOC;
        BrightField;
        DarkField;
        PresetLine;
        ExposureTime;
        Gain;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MCIOC, CameraIOC, Cameraname)
            % File works where first 2 entries are bright field, second 2
            % are dark field. First entry in each pair is exposure time,
            % second entry is gain.
            
            % gets IOC names
            app.MCIOC = MCIOC;
            app.CameraIOC = CameraIOC;
            
            % gets GUI name
            app.UIFigure.Name = ['LED ' Cameraname ' Controls'];
            
            % read presets as described above
            PresetsTextFileData = readcell([app.CameraIOC '_Presets.txt']);
            app.PresetLine = [PresetsTextFileData{end,1:(length(PresetsTextFileData(end,:))-1)}] ;
            app.BrightField = app.PresetLine(1:2);
            app.DarkField = app.PresetLine(3:4);
            lcaPutNoWait([app.CameraIOC ':cam1:AcquireTime'],app.DarkField(1))
            app.ExposuretimesEditField.Value = lcaGet([app.CameraIOC ':cam1:AcquireTime']);
            app.ExposureTime = app.ExposuretimesEditField.Value;
            lcaPutNoWait([app.CameraIOC ':cam1:Gain'],app.DarkField(2))
            app.GaindBEditField.Value = lcaGet([app.CameraIOC ':cam1:Gain']);
            app.Gain = app.GaindBEditField.Value;
            
            % checks if the LED is turned on when the GUI opens
            if strcmp(lcaGet(app.MCIOC),'High')
                app.LEDstatusSwitch.Value = 'On';
            else
                app.LEDstatusSwitch.Value = 'Off';
            end
        end

        % Value changed function: LEDstatusSwitch
        function LEDstatusSwitchValueChanged(app, event)
            % turns LED on or off and appropriately changes the camera
            % gain and exposure time to bright or dark field
            LEDStatus = app.LEDstatusSwitch.Value;
            if strcmp(LEDStatus,'On')
                lcaPutNoWait(app.MCIOC,1)
                app.ExposureTime = app.BrightField(1);
                lcaPutNoWait([app.CameraIOC ':cam1:AcquireTime'],app.ExposureTime)
                app.ExposuretimesEditField.Value = app.ExposureTime;
                app.Gain = app.BrightField(2);
                lcaPutNoWait([app.CameraIOC ':cam1:Gain'],app.Gain)
                app.GaindBEditField.Value = app.Gain;
            else
                lcaPutNoWait(app.MCIOC,0)
                app.ExposureTime = app.DarkField(1);
                lcaPutNoWait([app.CameraIOC ':cam1:AcquireTime'],app.ExposureTime)
                app.ExposuretimesEditField.Value = app.ExposureTime;
                app.Gain = app.DarkField(2);
                lcaPutNoWait([app.CameraIOC ':cam1:Gain'],app.Gain)
                app.GaindBEditField.Value = app.Gain;
            end
        end

        % Value changed function: ExposuretimesEditField
        function ExposuretimesEditFieldValueChanged(app, event)
            % sets new exposure time value
            lcaPutNoWait([app.CameraIOC ':cam1:AcquireTime'],app.ExposuretimesEditField.Value)
            app.ExposureTime = app.ExposuretimesEditField.Value;
        end

        % Value changed function: GaindBEditField
        function GaindBEditFieldValueChanged(app, event)
            % sets new gain value
            lcaPutNoWait([app.CameraIOC ':cam1:Gain'],app.GaindBEditField.Value)
            app.Gain = app.GaindBEditField.Value;
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            % saves new preset values to file as described in the startup
            % function
            if strcmp(app.LEDstatusSwitch.Value, 'On')
                app.BrightField(1) = app.ExposuretimesEditField.Value;
                app.BrightField(2) = app.GaindBEditField.Value;
                app.PresetLine(1:2) = app.BrightField; 
            else
                app.DarkField(1) = app.ExposuretimesEditField.Value;
                app.DarkField(2) = app.GaindBEditField.Value;
                app.PresetLine(3:4) = app.DarkField;
            end
            writtentext = {app.PresetLine,datestr(datetime('now'))};
            writecell(writtentext,[app.CameraIOC '_Presets.txt'],'WriteMode','append')
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % when GUI closed, turns of LED
            delete(app)
            lcaPutNoWait([app.MCIOC],0)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.LEDstatusSwitchLabel = uilabel(app.UIFigure);
            app.LEDstatusSwitchLabel.Text = 'LED status';
            app.LEDstatusSwitch = uiswitch(app.UIFigure, 'slider');
            app.LEDstatusSwitch.ValueChangedFcn = createCallbackFcn(app, @LEDstatusSwitchValueChanged, true);
            app.ExposuretimesEditFieldLabel = uilabel(app.UIFigure);
            app.ExposuretimesEditFieldLabel.Text = 'Exposure time (s)';
            app.ExposuretimesEditField = uieditfield(app.UIFigure, 'numeric');
            app.ExposuretimesEditField.ValueChangedFcn = createCallbackFcn(app, @ExposuretimesEditFieldValueChanged, true);
            app.GaindBEditFieldLabel = uilabel(app.UIFigure);
            app.GaindBEditFieldLabel.Text = 'Gain (dB)';
            app.GaindBEditField = uieditfield(app.UIFigure, 'numeric');
            app.GaindBEditField.ValueChangedFcn = createCallbackFcn(app, @GaindBEditFieldValueChanged, true);
            app.SAVEButton = uibutton(app.UIFigure, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LED_GUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end