classdef Laser_dashboard_first_xrays < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Image                    matlab.ui.control.Image
        IREXITMirrorLabel        matlab.ui.control.Label
        Panel_1                  matlab.ui.container.Panel
        Panel_2                  matlab.ui.container.Panel
        IREXITLensLabel          matlab.ui.control.Label
        Panel_3                  matlab.ui.container.Panel
        Panel_4                  matlab.ui.container.Panel
        Panel_5                  matlab.ui.container.Panel
        IRENTMirrorLabel         matlab.ui.control.Label
        Panel_6                  matlab.ui.container.Panel
        Panel_7                  matlab.ui.container.Panel
        IRENTLensLabel           matlab.ui.control.Label
        Panel_8                  matlab.ui.container.Panel
        Panel_9                  matlab.ui.container.Panel
        Panel_10                 matlab.ui.container.Panel
        Panel_15                 matlab.ui.container.Panel
        Panel_13                 matlab.ui.container.Panel
        UIAxes                   matlab.ui.control.UIAxes
        Panel_14                 matlab.ui.container.Panel
        UIAxes2                  matlab.ui.control.UIAxes
        Panel_16                 matlab.ui.container.Panel
        EbeamviewingCameraLabel  matlab.ui.control.Label
        LaserviewingCameraLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        testobj1 = [];
        testobj2 = [];
        testobj3 = [];
        testobj4 = [];
        testobj5 = [];
        testobj6 = [];
        testobj7 = [];
        testobj8 = [];
        testobj9 = [];
        testobj10 = [];
        cameraobj = [];
        cameraobj2 = [];
        TimerNames;
        Camera_IOC;
        MCIOC;
        ISSI_IOC;
        Distances;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % makes parent GUI invisible while child GUI is initialized
            app.UIFigure.Visible = 'off';
            app.UIFigure.Name = 'Laser Dashboard';
            % names of IOCs and panels that will be passed to child GUIs
            % to finish GUI, fill in proper IOC, Camera IOC, and timer
            % names
            IOCnames = ["MOTR:ICS1:90:P" "MOTR:ICS1:90:Y" "MOTR:ICS1:100:V" "MOTR:ICS1:100:Z"...
                "MOTR:ICS1:80:H" "MOTR:ICS1:80:P" "MOTR:ICS1:80:Y" "MOTR:ICS1:70:H" "MOTR:ICS1:70:V" "MOTR:ICS1:70:Z"]; 
            Names = ["IR Exit Mirror Pitch-AXIS" "IR Exit Mirror Yaw-AXIS" ...
                "IR Exit Lens X-AXIS" "IR Exit Lens Y-AXIS" "IR Exit Lens Z-AXIS" ...
                "IR Ent Mirror Pitch-AXIS" "IR Ent Mirror Yaw-AXIS" ...
                "IR Ent Lens X-AXIS" "IR Ent Lens Y-AXIS" "IR Ent Lens Z-AXIS"];
            app.TimerNames = "Laser_dash"; 
            app.ISSI_IOC = ["Dev_LC_1" "Dev_LC_2"];
            app.Camera_IOC = ["CAM:ICS1:20" "CAM:ICS1:40"];
            app.MCIOC = ["EDIO24:Bo2" "EDIO24:Bo4"];
            app.Distances = [10000 11000 1.5 1.4 1.3 10000 11000 1.5 1.4 1.3];

            % Preset names that will be passed to child GUIs
            % N.B. If you want to change the number of presets, every line
            % in the file must have the same number of presets to work
            % properly. If you want to maintain a record of the file with a
            % different number of presets, duplicate and save the file.
            % Preset names are placeholders
            PPresetNamesExM = {'OUT','IN' , 'CP1', 'CP2'};
            YPresetNamesExM = {'OUT','IN' , 'CP1', 'CP2'};
            XPresetNamesExL = {'OUT','IN' , 'CP1', 'CP2'};
            YPresetNamesExL = {'OUT','IN' , 'CP1', 'CP2'};
            ZPresetNamesExL = {'OUT','IN' , 'CP1', 'CP2'};
            PPresetNamesEnM = {'OUT','IN' , 'CP1', 'CP2'};
            YPresetNamesEnM = {'OUT','IN' , 'CP1', 'CP2'};
            XPresetNamesEnL = {'OUT','IN' , 'CP1', 'CP2'};
            YPresetNamesEnL = {'OUT','IN' , 'CP1', 'CP2'};
            ZPresetNamesEnL = {'OUT','IN' , 'CP1', 'CP2'};
            
            %combined into single cell that way we only need to index this
            %cell to use the set of preset names as an input argument
            Presets = {PPresetNamesExM; YPresetNamesExM; XPresetNamesExL; YPresetNamesExL; ZPresetNamesExL; ...
               PPresetNamesEnM; YPresetNamesEnM; XPresetNamesEnL; YPresetNamesEnL; ZPresetNamesEnL};
            
            % creates structure to store elements of child GUIs
            hobjects(1:length(IOCnames)) = struct();
            camobjects = struct();
            camobjects2 = struct();
            
            % Loop sets property for child GUI to the child GUI, fills the
            % structure with the child GUI elements and passes IOC name to
            % child GUI
            for ii = 1:length(IOCnames)
                app.(['testobj' num2str(ii)]) = embed_GUI(0,Names{ii},Presets{ii},convertStringsToChars(IOCnames(ii)),convertStringsToChars(app.TimerNames),app.UIFigure.Name,app.Distances(ii)); % test_embed is linked gui
                hobjects(ii).MyNewField = app.(['testobj' num2str(ii)]).embeddedapp.Children;   
            end
            
            app.cameraobj = Camera_Panel(0,convertStringsToChars(app.Camera_IOC(1)),convertStringsToChars(app.MCIOC(1)),convertStringsToChars(app.ISSI_IOC(1)),'Laser Dashboard E-beam',app.UIAxes);
            camobjects.MyNewField = app.cameraobj.camera_embed.Children;
            
            app.cameraobj2 = Camera_Panel(0,convertStringsToChars(app.Camera_IOC(2)),convertStringsToChars(app.MCIOC(2)),convertStringsToChars(app.ISSI_IOC(2)),'Laser Dashboard Laser',app.UIAxes_2);
            camobjects2.MyNewField = app.cameraobj2.camera_embed.Children;
            
           % now that child GUI is initialized, make parent GUI visible
           app.UIFigure.Visible = 'on';
           
           % loop puts child GUIs in panels on parent GUI and passes name
           % for the panel and IOC name to child GUI
           
           for idx = 1:length(camobjects)
                camobjects.MyNewField.Parent = app.Panel_15;
           end
            
            app.cameraobj.camera_embed.Visible = 'off';
            
            for idx = 1:length(camobjects2)
                camobjects2.MyNewField.Parent = app.Panel_16;
           end
            
            app.cameraobj2.camera_embed.Visible = 'off';
           
           for iii = 1:length(IOCnames)
                for idx = 1: length((['hobjects' num2str(iii)]))
                    hobjects(iii).MyNewField.Parent = app.(['Panel_' num2str(iii)]);
                end
                
%                 app.(['testobj' num2str(iii)]).config(convertStringsToChars(Names(iii)));
                
                % makes child GUIs as individual GUIs invisible
                app.(['testobj' num2str(iii)]).embeddedapp.Visible = 'off';
                
           end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            for ii = 1:10
                app.(['testobj' num2str(ii)]).killTimer(convertStringsToChars(app.TimerNames));
            end
            delete(app)
        end

        % Callback function
        function EbeamCameraSettingsButtonPushed(app, event)
%             path = '/usr/local/EPICS_NEW/synApps_6_1/support/areaDetector/ADVimba/op/adl';
%             system(['cd ' path])
%             system(['medm -x -macro "P=' convertStringsToChars(app.Camera_IOC(1)) ':,R=cam1:" ADVimba.adl'])
            Cameragui = findall(0, 'Name', 'Laser Dashboard E-beam Camera Controls');
            if ~isempty(Cameragui) == 0
                Camera_Control_GUI(convertStringsToChars(app.Camera_IOC(1)), [app.UIFigure.Name ' E-beam'])
            end
        end

        % Callback function
        function EbeamCameraAcquireButtonValueChanged(app, event)
            colorbar(app.UIAxes);
            while true
                if app.EbeamCameraAcquireButton.Value
                    app.EbeamLamp.Color = 'g';
                    data = profmon_grab(convertStringsToChars(app.Camera_IOC(1)));
                    profmon_imgPlot(data,'axes',app.UIAxes);
                    pause(0.15)
                else
                    app.EbeamLamp.Color = 'r';
                    break
                end
            end
        end

        % Callback function
        function CAM1ImageButtonValueChanged(app, event)
             while true
                if app.CAM1ImageButton.Value
                    data = profmon_grab(convertStringsToChars(app.Camera_IOC(1)));
                    profmon_imgPlot(data);
                    pause(0.15)
                else
%                     close('all')
                    break
                end
             end
        end

        % Callback function
        function LaserCameraAcquireButtonValueChanged(app, event)
            colorbar(app.UIAxes2);
            while true
                if app.LaserCameraAcquireButton.Value
                    app.EbeamLamp.Color = 'g';
                    data = profmon_grab(convertStringsToChars(app.Camera_IOC(2)));
                    profmon_imgPlot(data,'axes',app.UIAxes2);
                    pause(0.15)
                else
                    app.EbeamLamp.Color = 'r';
                    break
                end
            end
        end

        % Callback function
        function CAM2ImageButtonValueChanged(app, event)
            while true
                if app.CAM2ImageButton.Value
                    data = profmon_grab(convertStringsToChars(app.Camera_IOC(2)));
%                     colorbar;
                    profmon_imgPlot(data);
                    pause(0.15)
                else
%                     close('all')
                    break
                end
            end
        end

        % Callback function
        function LaserCameraSettingsButtonPushed(app, event)
%             path = '/usr/local/EPICS_NEW/synApps_6_1/support/areaDetector/ADVimba/op/adl';
%             system(['cd ' path])
%             system(['medm -x -macro "P=' convertStringsToChars(app.Camera_IOC(2)) ':,R=cam1:" ADVimba.adl'])
            Cameragui = findall(0, 'Name', 'Laser Dashboard Laser Camera Controls');
            if ~isempty(Cameragui) == 0
                Camera_Control_GUI(convertStringsToChars(app.Camera_IOC(2)), [app.UIFigure.Name ' Laser'])
            end
        end

        % Callback function
        function EbeamISSISettingsButtonPushed(app, event)
            ISSIgui = findall(0, 'Name', 'E-beam ISSI Controls');
            if ~isempty(ISSIgui) == 0
                ISSI_GUI(convertStringsToChars(app.ISSI_IOC(1)), 'E-beam')
            else
            end
        end

        % Callback function
        function LaserISSISettingsButtonPushed(app, event)
            ISSIgui = findall(0, 'Name', 'Laser ISSI Controls');
            if ~isempty(ISSIgui) == 0
                ISSI_GUI(convertStringsToChars(app.ISSI_IOC(2)), 'Laser')
            else
            end
        end

        % Callback function
        function EbeamLEDControlsButtonPushed(app, event)
            LEDIOC = 'brr br brrr';
            LEDgui = findall(0, 'Name', 'LED Interaction Point YAG E-beam Controls');
            if ~isempty(LEDgui) == 0
                LED_GUI(LEDIOC,convertStringsToChars(app.Camera_IOC(1)),[app.UIFigure.Name ' E-beam'])
            end
        end

        % Callback function
        function LaserLEDControlsButtonPushed(app, event)
            LEDIOC = 'brr br brrr';
            LEDgui = findall(0, 'Name', 'LED Interaction Point YAG Laser Controls');
            if ~isempty(LEDgui) == 0
                LED_GUI(LEDIOC,convertStringsToChars(app.Camera_IOC(2)),[app.UIFigure.Name ' Laser'])
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'ICSIP combined laser dashboard.png';
            app.IREXITMirrorLabel = uilabel(app.UIFigure);
            app.IREXITMirrorLabel.Text = 'IR EXIT Mirror';
            app.Panel_1 = uipanel(app.UIFigure);
            app.Panel_2 = uipanel(app.UIFigure);
            app.IREXITLensLabel = uilabel(app.UIFigure);
            app.IREXITLensLabel.Text = 'IR EXIT Lens';
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_4 = uipanel(app.UIFigure);
            app.Panel_5 = uipanel(app.UIFigure);
            app.IRENTMirrorLabel = uilabel(app.UIFigure);
            app.IRENTMirrorLabel.Text = 'IR ENT Mirror';
            app.Panel_6 = uipanel(app.UIFigure);
            app.Panel_7 = uipanel(app.UIFigure);
            app.IRENTLensLabel = uilabel(app.UIFigure);
            app.IRENTLensLabel.Text = 'IR ENT Lens';
            app.Panel_8 = uipanel(app.UIFigure);
            app.Panel_9 = uipanel(app.UIFigure);
            app.Panel_10 = uipanel(app.UIFigure);
            app.Panel_15 = uipanel(app.UIFigure);
            app.Panel_13 = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel_13);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel_14 = uipanel(app.UIFigure);
            app.UIAxes2 = uiaxes(app.Panel_14);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Panel_16 = uipanel(app.UIFigure);
            app.EbeamviewingCameraLabel = uilabel(app.UIFigure);
            app.EbeamviewingCameraLabel.Text = 'E-beam-viewing Camera';
            app.LaserviewingCameraLabel = uilabel(app.UIFigure);
            app.LaserviewingCameraLabel.Text = 'Laser-viewing Camera';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Laser_dashboard_first_xrays

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end