classdef MORE_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        MOREmenuLabel                 matlab.ui.control.Label
        SaveAllParametersButton       matlab.ui.control.Button
        SetZeroPositionsButton        matlab.ui.control.Button
        CurrentLabel                  matlab.ui.control.Label
        NewLabel                      matlab.ui.control.Label
        VelocitystepssLabel           matlab.ui.control.Label
        CurrentVelocity               matlab.ui.control.NumericEditField
        NewVelocity                   matlab.ui.control.NumericEditField
        Accelerationmms2Label         matlab.ui.control.Label
        CurrentAccl                   matlab.ui.control.NumericEditField
        NewAccl                       matlab.ui.control.NumericEditField
        SaveVelocity                  matlab.ui.control.Button
        SaveAccel                     matlab.ui.control.Button
        NegLimitstepsLabel            matlab.ui.control.Label
        CurrentNegLimit               matlab.ui.control.NumericEditField
        NewNegLimit                   matlab.ui.control.NumericEditField
        PosLimitstepsLabel            matlab.ui.control.Label
        CurrentPosLimit               matlab.ui.control.NumericEditField
        NewPosLimit                   matlab.ui.control.NumericEditField
        SaveNegLimit                  matlab.ui.control.Button
        SavePosLimit                  matlab.ui.control.Button
        STANDARDPRESETSDropDownLabel  matlab.ui.control.Label
        STANDARDPRESETSDropDown       matlab.ui.control.DropDown
        CurrentLabel_5                matlab.ui.control.Label
        NewLabel_5                    matlab.ui.control.Label
        CurrentStandard               matlab.ui.control.NumericEditField
        NewStandard                   matlab.ui.control.NumericEditField
        SaveStandard                  matlab.ui.control.Button
        CurrentLabel_6                matlab.ui.control.Label
        NewLabel_6                    matlab.ui.control.Label
        CurrentCustom                 matlab.ui.control.NumericEditField
        NewCustom                     matlab.ui.control.NumericEditField
        SaveCustom                    matlab.ui.control.Button
        CUSTOMPRESETSDropDownLabel    matlab.ui.control.Label
        CUSTOMPRESETSDropDown         matlab.ui.control.DropDown
        MoveHomeButton                matlab.ui.control.Button
    end


    properties (Access = public)
        moreIOCname
        MotorPVList
        MotorLowLimit
        MotorHighLimit
        BooleanMonitorValues
        GUIname
        Panelname
        PasswordCorrect
        units
    end
    
    properties (Access = private)
        MotorXPresets
        MotorCustomPresets
        MoreTimer
        MotorPosition
    end
    
    methods (Access = private)
        % checks if PVs have been changed and updates on GUI 
        function CheckMonitors(app)
            app.BooleanMonitorValues = lcaNewMonitorValue(app.MotorPVList(1:length(app.MotorPVList)));
            for ii = 1:length(app.MotorPVList)
                if app.BooleanMonitorValues(ii)
                    switch ii
                        case 1
                            app.CurrentVelocity.Value = lcaGet(app.MotorPVList(1));
                        case 2
                            app.CurrentAccl.Value = lcaGet(app.MotorPVList(2));
                        case 3
                            app.CurrentNegLimit.Value = lcaGet(app.MotorPVList(3));
                            app.MotorLowLimit = app.CurrentNegLimit.Value;
                        case 4
                            app.CurrentPosLimit.Value = lcaGet(app.MotorPVList(4));
                            app.MotorHighLimit = app.CurrentPosLimit.Value;
                        case 5
                            app.MotorPosition = lcaGet(app.MotorPVList(5));
                    end
                end
            end
        end
    end
    
    methods (Access = public)
        % function that asks to enter password and checks if it is
        % correct
        function PasswordCheck(app)
            app.PasswordCorrect = 0;
            X = inputdlg('Enter Password');
            password = 'labuser'; % do we want a more secure password
            if strcmp(X, password)
                app.PasswordCorrect = 1;
            else
                app.PasswordCorrect = 0;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, IOCname, presets, varargin)
            % Almost all user inputs with this GUI requires password
            % verification because changing these PVs could seriously
            % damage the equipment in the chamber
            
            % gets the IOC name from the axis panel
            app.moreIOCname = IOCname ;
            
            % sets the name of the GUI
            app.GUIname = varargin{1};
            app.Panelname = varargin{2};
            app.UIFigure.Name = [app.GUIname ' ' app.Panelname ' MORE'];
            
            % gets the units and sets what labels to use
            app.units = varargin{3};
            if strcmp(app.units, 'mm')
                app.Accelerationmms2Label.Text = 'Acceleration (mm/s^2)';
                app.VelocitystepssLabel.Text = 'Velocity (mm/s)';
                app.NegLimitstepsLabel.Text = 'Neg Limit (mm)';
                app.PosLimitstepsLabel.Text = 'Pos Limit (mm)';
            elseif strcmp(app.units, 'deg')
                app.Accelerationmms2Label.Text = 'Acceleration (deg/s^2)';
                app.VelocitystepssLabel.Text = 'Velocity (deg/s)';
                app.NegLimitstepsLabel.Text = 'Neg Limit (deg)';
                app.PosLimitstepsLabel.Text = 'Pos Limit (deg)';
            elseif strcmp(app.units, 'steps')
                app.Accelerationmms2Label.Text = 'Acceleration (s)';
                app.VelocitystepssLabel.Text = 'Velocity (steps/s)';
                app.NegLimitstepsLabel.Text = 'Neg Limit (steps)';
                app.PosLimitstepsLabel.Text = 'Pos Limit (steps)';
            end

            % disables editing PV readback text fields
            app.CurrentVelocity.Editable = 'off';
            app.CurrentAccl.Editable = 'off';
            app.CurrentNegLimit.Editable = 'off';
            app.CurrentPosLimit.Editable = 'off';
            app.CurrentStandard.Editable = 'off';
            app.CurrentCustom.Editable = 'off';
            
            % gets initial values of PVs and puts in readback fields
            app.CurrentVelocity.Value = lcaGet([app.moreIOCname '.VELO']);
            app.CurrentAccl.Value = lcaGet([app.moreIOCname '.ACCL']);
            app.CurrentNegLimit.Value = lcaGet([app.moreIOCname '.LLM']);
            app.MotorLowLimit = app.CurrentNegLimit.Value;
            app.CurrentPosLimit.Value = lcaGet([app.moreIOCname '.HLM']);
            app.MotorHighLimit = app.CurrentPosLimit.Value;
            app.MotorPosition = lcaGet([app.moreIOCname '.RBV']);

            % PVs to be modified by this GUI
            app.MotorPVList = {...
                [app.moreIOCname '.VELO']
                [app.moreIOCname '.ACCL']
                [app.moreIOCname '.LLM']
                [app.moreIOCname '.HLM']
                [app.moreIOCname '.RBV']
            };
            
            % loads file with standard presets
            PresetsTextFileData = readcell([app.moreIOCname '_Presets.txt']);
            app.MotorXPresets = [PresetsTextFileData{end,1:(length(PresetsTextFileData(end,:))-1)}] ;
            
            % picks standard presets out of preset array
            app.STANDARDPRESETSDropDown.Items = presets(1:length(app.MotorXPresets));
            
            % loads file with custom presets
            CustomPresetsTextFileData = readcell([app.moreIOCname '_Custom_Presets.txt']);
            app.MotorCustomPresets = [CustomPresetsTextFileData{end,1:(length(CustomPresetsTextFileData(end,:))-1)}] ;
            
            % picks custom presets out of preset array
            app.CUSTOMPRESETSDropDown.Items = presets((length(app.MotorXPresets)+1):end);
            
            % initializes current standard and custom preset text fields
            app.CurrentStandard.Value = app.MotorXPresets(1);
            app.CurrentCustom.Value = app.MotorCustomPresets(1);
            
            % sets monitor
            lcaSetMonitor(app.MotorPVList(1:length(app.MotorPVList)))
            
            % define timer as property
            app.MoreTimer = timer('Name', 'MoreTimer', ...
                'Period', 0.25, ...
                'StartDelay', 3, ...
                'TasksToExecute', inf, ...
                'ExecutionMode','FixedRate', ...
                'TimerFcn', @(varargin)CheckMonitors(app));
            
            % starts the timer
            t_Timer = app.MoreTimer ;
            start(t_Timer)
        end

        % Button pushed function: SaveAllParametersButton
        function SaveAllParametersButtonPushed(app, event)
            % saves all parameters that are set to be updated in the GUI
            % in other words, don't have to press each individual save button
            % but doesn't affect presets
            PasswordCheck(app)
            if app.PasswordCorrect
                lcaPutNoWait(app.MotorPVList(1),app.NewVelocity.Value)
                lcaPutNoWait(app.MotorPVList(2),app.NewAccl.Value)
                lcaPutNoWait(app.MotorPVList(3),app.NewNegLimit.Value)
                lcaPutNoWait(app.MotorPVList(4),app.NewPosLimit.Value)
            else
                errordlg('Incorrect password')
            end
        end

        % Button pushed function: SetZeroPositionsButton
        function SetZeroPositionsButtonPushed(app, event)
            % zeroes value of the motor (DOES NOT CHANGE ITS POSITION) 
            PasswordCheck(app)
            if app.PasswordCorrect
                oldLLM = lcaGet([app.moreIOCname '.LLM']);
                oldHLM = lcaGet([app.moreIOCname '.HLM']);
                lcaPutNoWait([app.moreIOCname '.SET'],1)
                lcaPutNoWait([app.moreIOCname '.VAL'],0)
                lcaPutNoWait([app.moreIOCname '.SET'],0)
                lcaPutNoWait([app.moreIOCname '.LLM'],oldLLM)
                lcaPutNoWait([app.moreIOCname '.HLM'],oldHLM)
            else
                errordlg('Incorrect password')
            end
        end

        % Button pushed function: SaveCustom
        function SaveCustomPushed(app, event)
         % determines which custom preset will be changed, saves the value,
         % then clears the file and writes the new custom preset array to
         % the file
         PasswordCheck(app)
         if app.PasswordCorrect
             app.CurrentCustom.Value = app.NewCustom.Value;
             for ii = 1:length(app.MotorCustomPresets)
                 if strcmp(app.CUSTOMPRESETSDropDown.Value,app.CUSTOMPRESETSDropDown.Items(ii))
                     app.MotorCustomPresets(ii) = app.NewCustom.Value;
                     writtentext = {app.MotorCustomPresets,datestr(datetime('now'))};
                     writecell(writtentext,[app.moreIOCname '_Custom_Presets.txt'],'WriteMode','append')
                 else
                 end
             end
         else
                errordlg('Incorrect password')
         end
        end

        % Button pushed function: SaveVelocity
        function SaveVelocityButtonPushed(app, event)
            % Changes velocity to new value
            PasswordCheck(app)
            if app.PasswordCorrect
                lcaPut(app.MotorPVList(1),app.NewVelocity.Value)
            else
                errordlg('Incorrect password')
            end
        end

        % Button pushed function: SaveAccel
        function SaveAccelButtonPushed(app, event)
            % Changes acceleration to new value
            PasswordCheck(app)
            if app.PasswordCorrect
                lcaPutNoWait(app.MotorPVList(2),app.NewAccl.Value)
            else
                errordlg('Incorrect password')
            end
        end

        % Button pushed function: SaveNegLimit
        function SaveNegLimitButtonPushed(app, event)
            % changes negative limit to new value
            PasswordCheck(app)
            if app.PasswordCorrect
                lcaPutNoWait(app.MotorPVList(3),app.NewNegLimit.Value)
            else
                errordlg('Incorrect password')
            end
        end

        % Button pushed function: SavePosLimit
        function SavePosLimitButtonPushed(app, event)
            % changes positive limit to new value
            PasswordCheck(app)
            if app.PasswordCorrect
                lcaPutNoWait(app.MotorPVList(4),app.NewPosLimit.Value)
            else
                errordlg('Incorrect password')
            end
        end

        % Button pushed function: SaveStandard
        function SaveStandardButtonPushed(app, event)
         % determines which preset will be changed, saves the value, then
         % clears the file and writes the new preset array to the file
         PasswordCheck(app)
         if app.PasswordCorrect
            app.CurrentStandard.Value = app.NewStandard.Value;
            for ii = 1:length(app.MotorXPresets)
                 if strcmp(app.STANDARDPRESETSDropDown.Value,app.STANDARDPRESETSDropDown.Items(ii))
                     app.MotorXPresets(ii) = app.NewStandard.Value;
                     writtentext = {app.MotorXPresets,datestr(datetime('now'))};
                     writecell(writtentext,[app.moreIOCname '_Presets.txt'],'WriteMode','append')
                 else
                 end
            end
         else
                errordlg('Incorrect password')
         end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % closes GUI and stops then deletes timer
            clear app.MoreTimer
            stop(app.MoreTimer)
            delete(app.MoreTimer)
            delete(app)
        end

        % Value changed function: STANDARDPRESETSDropDown
        function STANDARDPRESETSDropDownValueChanged(app, event)
            % when the preset value is changed, makes sure that the updated
            % preset value is shown in the current text field
            for i = 1:length(app.MotorXPresets)
                if strcmp(app.STANDARDPRESETSDropDown.Value,app.STANDARDPRESETSDropDown.Items(i))
                    app.CurrentStandard.Value = app.MotorXPresets(i);
                end
            end
        end

        % Value changed function: CUSTOMPRESETSDropDown
        function CUSTOMPRESETSDropDownValueChanged(app, event)
            % when the custom preset value is changed, makes sure that the
            % updated custom preset value is shown in the current text field
            for i = 1:length(app.MotorCustomPresets)
                if strcmp(app.CUSTOMPRESETSDropDown.Value,app.CUSTOMPRESETSDropDown.Items(i))
                    app.CurrentCustom.Value = app.MotorCustomPresets(i);
                end
            end            
        end

        % Button pushed function: MoveHomeButton
        function MoveHomeButtonPushed(app, event)
            % moves motor to its home position
            opts = struct('WindowStyle','Modal','Interpreter','tex');
            warndlg('\color{red} \bf \fontsize{15} Verify you are following the proper homing sequence. Not following could cause SEVERE DAMAGE.','Homing Warning',opts)
            pause(3)
            PasswordCheck(app)
            if app.PasswordCorrect
                % need these if/else statements to prevent collisions in
                % the chamber
                if strcmp(app.moreIOCname,'MOTR:ICS1:100:V')
                    zpos = lcaGet('MOTR:ICS1:100:Z.RBV');
                    if zpos > 4.5
                        lcaPutNoWait([app.moreIOCname '-home'],1)
                    else
                        errordlg('Move IR Exit Lens z-axis to greater than 4.5 mm.')
                    end
                elseif strcmp(app.moreIOCname,'MOTR:ICS1:20:Z')
                    ypos = lcaGet('MOTR:ICS1:20:V.RBV');
                    if ypos < -1
                        lcaPutNoWait([app.moreIOCname '-home'],1)
                        elses
                        errordlg('Move IP YAG y-axis to less than -1 mm.')
                    end
                elseif strcmp(app.moreIOCname, 'MOTR:ICS1:20:V')
                    lcaPutNoWait([app.moreIOCname '-home'],1)
                    pause(25)
                    lcaPutNoWait('MOTR:ICS1:20:V2-home',1)
                elseif strcmp(app.moreIOCname, 'MOTR:ICS1:20:H')
                    zpos = lcaGet('MOTR:ICS1:20:Z.RBV');
                    if zpos > 3.5
                        lcaPutNoWait([app.moreIOCname '-home'],1)
                    else
                        errordlg('Move IP YAG z-axis to greater than 3.5 mm.')
                    end
                else
                    lcaPutNoWait([app.moreIOCname '-home'],1)
                end
%                 pause() % time to allow procedure to complete 
                % write timestamp to file
            else
                errordlg('Incorrect password')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.MOREmenuLabel = uilabel(app.UIFigure);
            app.MOREmenuLabel.Text = 'MORE menu:';
            app.SaveAllParametersButton = uibutton(app.UIFigure, 'push');
            app.SaveAllParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAllParametersButtonPushed, true);
            app.SaveAllParametersButton.Text = 'Save All Parameters';
            app.SetZeroPositionsButton = uibutton(app.UIFigure, 'push');
            app.SetZeroPositionsButton.ButtonPushedFcn = createCallbackFcn(app, @SetZeroPositionsButtonPushed, true);
            app.SetZeroPositionsButton.Text = 'Set Zero Positions';
            app.CurrentLabel = uilabel(app.UIFigure);
            app.CurrentLabel.Text = 'Current';
            app.NewLabel = uilabel(app.UIFigure);
            app.NewLabel.Text = 'New';
            app.VelocitystepssLabel = uilabel(app.UIFigure);
            app.VelocitystepssLabel.Text = 'Velocity (steps/s)';
            app.CurrentVelocity = uieditfield(app.UIFigure, 'numeric');
            app.CurrentVelocity.ValueDisplayFormat = '%.5f';
            app.NewVelocity = uieditfield(app.UIFigure, 'numeric');
            app.NewVelocity.ValueDisplayFormat = '%.5f';
            app.Accelerationmms2Label = uilabel(app.UIFigure);
            app.Accelerationmms2Label.Text = 'Acceleration (mm/s^2)';
            app.CurrentAccl = uieditfield(app.UIFigure, 'numeric');
            app.CurrentAccl.ValueDisplayFormat = '%.5f';
            app.NewAccl = uieditfield(app.UIFigure, 'numeric');
            app.NewAccl.ValueDisplayFormat = '%.5f';
            app.SaveVelocity = uibutton(app.UIFigure, 'push');
            app.SaveVelocity.ButtonPushedFcn = createCallbackFcn(app, @SaveVelocityButtonPushed, true);
            app.SaveVelocity.Text = 'SAVE';
            app.SaveAccel = uibutton(app.UIFigure, 'push');
            app.SaveAccel.ButtonPushedFcn = createCallbackFcn(app, @SaveAccelButtonPushed, true);
            app.SaveAccel.Text = 'SAVE';
            app.NegLimitstepsLabel = uilabel(app.UIFigure);
            app.NegLimitstepsLabel.Text = 'Neg Limit (steps)';
            app.CurrentNegLimit = uieditfield(app.UIFigure, 'numeric');
            app.CurrentNegLimit.ValueDisplayFormat = '%.5f';
            app.NewNegLimit = uieditfield(app.UIFigure, 'numeric');
            app.NewNegLimit.ValueDisplayFormat = '%.5f';
            app.PosLimitstepsLabel = uilabel(app.UIFigure);
            app.PosLimitstepsLabel.Text = 'Pos Limit (steps)';
            app.CurrentPosLimit = uieditfield(app.UIFigure, 'numeric');
            app.CurrentPosLimit.ValueDisplayFormat = '%.5f';
            app.NewPosLimit = uieditfield(app.UIFigure, 'numeric');
            app.NewPosLimit.ValueDisplayFormat = '%.5f';
            app.SaveNegLimit = uibutton(app.UIFigure, 'push');
            app.SaveNegLimit.ButtonPushedFcn = createCallbackFcn(app, @SaveNegLimitButtonPushed, true);
            app.SaveNegLimit.Text = 'SAVE';
            app.SavePosLimit = uibutton(app.UIFigure, 'push');
            app.SavePosLimit.ButtonPushedFcn = createCallbackFcn(app, @SavePosLimitButtonPushed, true);
            app.SavePosLimit.Text = 'SAVE';
            app.STANDARDPRESETSDropDownLabel = uilabel(app.UIFigure);
            app.STANDARDPRESETSDropDownLabel.Text = 'STANDARD PRESETS:';
            app.STANDARDPRESETSDropDown = uidropdown(app.UIFigure);
            app.STANDARDPRESETSDropDown.Items = {'P1', 'P2', 'P3', 'P4', 'P5', 'OUT', 'YAG'};
            app.STANDARDPRESETSDropDown.ValueChangedFcn = createCallbackFcn(app, @STANDARDPRESETSDropDownValueChanged, true);
            app.STANDARDPRESETSDropDown.Value = 'P1';
            app.CurrentLabel_5 = uilabel(app.UIFigure);
            app.CurrentLabel_5.Text = 'Current';
            app.NewLabel_5 = uilabel(app.UIFigure);
            app.NewLabel_5.Text = 'New';
            app.CurrentStandard = uieditfield(app.UIFigure, 'numeric');
            app.CurrentStandard.ValueDisplayFormat = '%.5f';
            app.NewStandard = uieditfield(app.UIFigure, 'numeric');
            app.NewStandard.ValueDisplayFormat = '%.5f';
            app.SaveStandard = uibutton(app.UIFigure, 'push');
            app.SaveStandard.ButtonPushedFcn = createCallbackFcn(app, @SaveStandardButtonPushed, true);
            app.SaveStandard.Text = 'SAVE';
            app.CurrentLabel_6 = uilabel(app.UIFigure);
            app.CurrentLabel_6.Text = 'Current';
            app.NewLabel_6 = uilabel(app.UIFigure);
            app.NewLabel_6.Text = 'New';
            app.CurrentCustom = uieditfield(app.UIFigure, 'numeric');
            app.CurrentCustom.ValueDisplayFormat = '%.5f';
            app.NewCustom = uieditfield(app.UIFigure, 'numeric');
            app.NewCustom.ValueDisplayFormat = '%.5f';
            app.SaveCustom = uibutton(app.UIFigure, 'push');
            app.SaveCustom.ButtonPushedFcn = createCallbackFcn(app, @SaveCustomPushed, true);
            app.SaveCustom.Text = 'SAVE';
            app.CUSTOMPRESETSDropDownLabel = uilabel(app.UIFigure);
            app.CUSTOMPRESETSDropDownLabel.Text = 'CUSTOM PRESETS:';
            app.CUSTOMPRESETSDropDown = uidropdown(app.UIFigure);
            app.CUSTOMPRESETSDropDown.Items = {'CP1', 'CP2', 'CP3', 'CP4', 'CP5'};
            app.CUSTOMPRESETSDropDown.ValueChangedFcn = createCallbackFcn(app, @CUSTOMPRESETSDropDownValueChanged, true);
            app.CUSTOMPRESETSDropDown.Value = 'CP1';
            app.MoveHomeButton = uibutton(app.UIFigure, 'push');
            app.MoveHomeButton.ButtonPushedFcn = createCallbackFcn(app, @MoveHomeButtonPushed, true);
            app.MoveHomeButton.Text = 'Move Home';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MORE_GUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end